classdef PipelineConfig_dlgBox < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        PipelineConfigurationLabel      matlab.ui.control.Label
        AvailablefunctionsListBoxLabel  matlab.ui.control.Label
        AvailablefunctionsListBox       matlab.ui.control.ListBox
        SelectedfunctionsListBoxLabel   matlab.ui.control.Label
        SelectedfunctionsListBox        matlab.ui.control.ListBox
        ButtonUp                        matlab.ui.control.Button
        ButtonDwn                       matlab.ui.control.Button
        SaveconfigButton                matlab.ui.control.Button
        ResetButton                     matlab.ui.control.Button
        SetOptionsButton                matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        AddButton                       matlab.ui.control.Button
        RemoveItemButton                matlab.ui.control.Button
        SavestepButton                  matlab.ui.control.Button
        SavetofileButton                matlab.ui.control.Button
        LoadfromfileButton              matlab.ui.control.Button
    end

    
    properties (Access = private)
        pipeObj % Instantiation of PipelineManager class.
        b_isStandAlone % True if calling app is DataViewer StandAlone version.
    end
    
    methods (Access = private)
        function createFuncList(app)
            % This function populates the "AvailablefunctionsListBox" with
            % the functions listed in "pipeObj".
            
            Items = {app.pipeObj.funcList.name};
            ItemsData = app.pipeObj.funcList;
            list_of_folders = cellfun(@(x) erase(x, app.pipeObj.fcnDir), {app.pipeObj.funcList.folder}, 'UniformOutput',false);
            if app.b_isStandAlone
                idx = contains(list_of_folders, 'DataImport') | contains(list_of_folders, 'DataExport'); % Remove all DataImport or DataExport options for the StandAlone version. The GUI has a separate button for that!
                list_of_folders(idx) = [];
                Items(idx) = [];
                ItemsData(idx) = [];
            end
            idx = arrayfun(@(x) ~isempty(x.info.opts), ItemsData);
            Items(idx) = cellfun(@(x) ['(!) ' x ], Items(idx), 'UniformOutput',false);
            [val,ind] = unique(list_of_folders);
            new_Items = {};
            new_ItemsData = [];
            for i = 1:numel(ind)
                header =['---------- ' val{i} ' ----------'];
                dummyData = ItemsData(1); dummyData.name = '----------';
                if i < numel(ind)
                    sub_Items = Items(ind(i):ind(i+1)-1);
                    sub_ItemsData = ItemsData(ind(i):ind(i+1)-1);
                else
                    sub_Items = Items(ind(i):end);
                    sub_ItemsData = ItemsData(ind(i):end);
                end
                new_Items = [new_Items, header, sub_Items];
                new_ItemsData = [new_ItemsData; dummyData; sub_ItemsData];
            end
            app.AvailablefunctionsListBox.Items = new_Items;
            app.AvailablefunctionsListBox.ItemsData = new_ItemsData;
            app.SelectedfunctionsListBox.Items = {};
            app.SelectedfunctionsListBox.ItemsData = [];
        end
        
        function b_ok = createPipe(app)
            % This function uses the content of the
            % SelectedfunctionsListBox to add each function to the
            % pipeObj's pipeline.
            % Output:
            %   b_ok (bool): If TRUE, all steps were added to the pipeline.
            %   If FALSE, an error occured or User decided to abort.
            % Erase existing pipeline:
            b_ok = false;
            if ~isempty(app.pipeObj)
                app.pipeObj.reset_pipe;
            end
            
            % Create pipeline
            ItemsData = app.SelectedfunctionsListBox.ItemsData;            
            for i = 1:numel(ItemsData)
                if ItemsData(i).b_save
                    state = app.pipeObj.addTask(ItemsData(i).name, ItemsData(i).b_save, ItemsData(i).datFileName);
                else
                    state = app.pipeObj.addTask(ItemsData(i).name);
                end
                % If failed to add a step to the pipeline, reset and
                % return.
                if ~state
                   app.pipeObj.reset_pipe;
                   return
                end
            end
            b_ok = true;
        end
        
        function fillPipeListBox(app)
            % This function uses the content of app.pipeObj.pipe to
            % display the pipeline steps in the SelectedfunctionsListBox as
            % well as to update the items with custom options such as
            % Save2File, custom opts settings.
            
            for i = 1:length(app.pipeObj.pipe)
                idx = strcmp(app.pipeObj.pipe(i).name, {app.AvailablefunctionsListBox.ItemsData.name});
                % Set optional parameters and other variables from saved
                % pipeline:
                app.AvailablefunctionsListBox.ItemsData(idx).info.opts = app.pipeObj.pipe(i).opts;
                app.AvailablefunctionsListBox.Value = app.AvailablefunctionsListBox.ItemsData(idx);
                app.AddButtonPushed;
                app.SelectedfunctionsListBox.ItemsData(end).b_save = app.pipeObj.pipe(i).b_save2File;
                app.SelectedfunctionsListBox.ItemsData(end).datFileName= app.pipeObj.pipe(i).datFileName;
                if ~isempty(app.pipeObj.pipe(i).datFileName)
                    app.SelectedfunctionsListBox.Items{end} =  [app.SelectedfunctionsListBox.Items{end}, '(Save to file: ', app.pipeObj.pipe(i).datFileName ')'];
                end
            end
            
        end
        
        function b_OK = checkPipeline(app)
            % This function verifies if the existing pipeline was altered
            % by changing the functions list, paramterers or saving options.
            
            b_OK = false;
            if isempty(app.pipeObj) || isempty(app.pipeObj.pipe)
                return
            end
            
            % First, check if the list of functions was not altered:
            fcnList = app.SelectedfunctionsListBox.ItemsData;
            if ~isempty(intersect({app.pipeObj.pipe.name}, {fcnList.name}))
                return
            end
            % Further checks:
            for i = 1:length(fcnList)
                idx = strcmp(fcnList(i).name, {app.pipeObj.pipe.name}) ;
                % Check if the optional parameters of the functions
                % were changed:
                if ~isequaln(fcnList(i).info.opts, app.pipeObj.pipe(idx).opts)
                    return
                end
                % Check if the saving options were changed:
                if fcnList(i).b_save ~= app.pipeObj.pipe(idx).b_save2File
                    return
                end
                % Check if the saving file name was changed:
                if ~strcmp(fcnList(i).datFileName, app.pipeObj.pipe(idx).datFileName)
                    return
                end
            end
            b_OK = true;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, pipeObj, b_isStandAlone)
            
            % Update UIFigure position:
            app.UIFigure.Visible ='off';
            movegui(app.UIFigure, 'center');
            app.pipeObj = pipeObj;
            app.b_isStandAlone = b_isStandAlone;
            app.createFuncList;
            if ~isempty(app.pipeObj.pipe)
                app.fillPipeListBox;
            end
            % ToolTips of buttons
            app.ButtonUp.Tooltip = 'Moves function up.';
            app.ButtonDwn.Tooltip = 'Moves function down.';
            %%
            app.UIFigure.Visible ='on';
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            %             app.CallingApp.ConfigureNewPipelineButton.Enable = 'on';
            delete(app)
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            
            if app.b_isStandAlone
                classSel = '';
            else
                classSel = app.pipeObj.ClassName;
            end
            FuncSel = app.AvailablefunctionsListBox.Value;
            if strcmp(FuncSel.name, '----------')
                return
            end
            idx = strcmp({app.AvailablefunctionsListBox.ItemsData.name}, FuncSel.name);
            idx_pipe = strcmp(app.AvailablefunctionsListBox.ItemsData(idx).name, {app.pipeObj.funcList.name});
            
            FuncName = app.AvailablefunctionsListBox.Items(idx);
            newItem = [classSel '-->> ' FuncName{:}];
            % Reset Item's data of selected functions list directly from pipeObj:
            newItemData = app.pipeObj.funcList(idx_pipe); newItemData.ClassName = classSel;
            % Add fields to save file:
            newItemData.b_save = false;
            newItemData.datFileName = '';
            % Control for already added items:
            if ~ismember(newItem, app.SelectedfunctionsListBox.Items)
                app.SelectedfunctionsListBox.Items = [app.SelectedfunctionsListBox.Items newItem];
                app.SelectedfunctionsListBox.ItemsData = [app.SelectedfunctionsListBox.ItemsData newItemData];
            end
        end

        % Button pushed function: ButtonUp
        function ButtonUpPushed(app, event)
            ItemData_selected = app.SelectedfunctionsListBox.Value;
            if~isempty(ItemData_selected)
                Items = app.SelectedfunctionsListBox.Items;
                ItemsData = app.SelectedfunctionsListBox.ItemsData;
                idx = arrayfun(@(x) isequaln(x,ItemData_selected), app.SelectedfunctionsListBox.ItemsData);
                pos = find(idx);
                if pos > 1
                    range = 1:numel(Items);
                    range(pos) = pos -1 ;
                    range(pos -1) = pos;
                    app.SelectedfunctionsListBox.Items = Items(range);
                    app.SelectedfunctionsListBox.ItemsData = ItemsData(range);
                end
            end
        end

        % Button pushed function: ButtonDwn
        function ButtonDwnPushed(app, event)
            ItemData_selected = app.SelectedfunctionsListBox.Value;
            if~isempty(ItemData_selected)
                Items = app.SelectedfunctionsListBox.Items;
                ItemsData = app.SelectedfunctionsListBox.ItemsData;
                idx = arrayfun(@(x) isequaln(x,ItemData_selected), app.SelectedfunctionsListBox.ItemsData);
                pos = find(idx);
                if pos < numel(Items)
                    range = 1:numel(Items);
                    range(pos) = pos + 1;
                    range(pos + 1) = pos;
                    app.SelectedfunctionsListBox.Items = Items(range);
                    app.SelectedfunctionsListBox.ItemsData = ItemsData(range);
                end
            end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.pipeObj.reset_pipe('all');
            app.createFuncList;
        end

        % Button pushed function: SetOptionsButton
        function SetOptionsButtonPushed(app, event)
            
            ItemData_selected = app.SelectedfunctionsListBox.Value;
            if ~isempty(ItemData_selected) && ~isempty(ItemData_selected.info.opts)
                % Hide app:
                app.UIFigure.Visible = 'off';
                app.pipeObj.setOpts(ItemData_selected.name);
                idx = arrayfun(@(x) isequaln(x,ItemData_selected), app.SelectedfunctionsListBox.ItemsData);
                idx_pipe = strcmp(ItemData_selected.name, {app.pipeObj.funcList.name});
                if ~isequaln(app.pipeObj.funcList(idx_pipe).info.opts, ItemData_selected.info.opts)
                    app.SelectedfunctionsListBox.Items(idx) = strrep(app.SelectedfunctionsListBox.Items(idx), '(!)', '(custom opts!)');
                end
                % Show app:
                app.UIFigure.Visible = 'on';
            end
            
        end

        % Value changed function: SelectedfunctionsListBox
        function SelectedfunctionsListBoxValueChanged(app, event)
            % This callback changes the name of the "Save Step button"
            % depending on the save/not-save state of the selected item of
            % the list.
            selection = app.SelectedfunctionsListBox.Value;
            Color = {[0.07,0.91,1], [0.97,0.44,0.35]};
            
            % Control for functions that do not have outputs:
            if ~any(ismember({'outData', 'outDataStat'}, selection.info.argsOut))
                app.SavestepButton.Enable = 'off';
                app.SavestepButton.Tooltip = 'This step does not output any data.';
            else
                app.SavestepButton.Enable ='on';
                app.SavestepButton.Tooltip = '';
            end
            
            % Change Button appearence if the step is set to be saved or not:
            if  ~isfield(selection, 'b_save') || isempty(selection.b_save) || ( islogical(selection.b_save) && ~selection.b_save )
                app.SavestepButton.Text = {'Save' ,'Step'};
                app.SavestepButton.BackgroundColor = Color{1};
                
            else
                app.SavestepButton.Text = {'Undo' ,'Save'};
                app.SavestepButton.BackgroundColor = Color{2};
            end
        end

        % Button pushed function: SavestepButton
        function SavestepButtonPushed(app, event)
            % This callback opens a dialog file to save a file from a
            % step in the pipeline.
            selection = app.SelectedfunctionsListBox.Value;
            if isempty(selection)
                return
            end
            idx = arrayfun(@(x) isequal(selection,x), app.SelectedfunctionsListBox.ItemsData);
            Color = {[0.07,0.91,1], [0.97,0.44,0.35]};
            if ~isfield(selection, 'b_save') || isempty(selection.b_save) || ( islogical(selection.b_save) && ~selection.b_save )
                selection = addDatFileName(selection);
                if ~selection.b_save
                    return
                end
                app.SelectedfunctionsListBox.ItemsData(idx).b_save = selection.b_save;
                app.SelectedfunctionsListBox.ItemsData(idx).datFileName = selection.datFileName;
                app.SavestepButton.Text = {'Undo', 'Save'};
                app.SavestepButton.BackgroundColor = Color{2};
                app.SelectedfunctionsListBox.Items{idx} =  [app.SelectedfunctionsListBox.Items{idx}, '(Save to file: ', selection.datFileName ')'];
            else
                app.SelectedfunctionsListBox.ItemsData(idx).b_save = false;
                app.SelectedfunctionsListBox.ItemsData(idx).datFileName = '';
                app.SavestepButton.Text = {'Save' ,'Step'};
                app.SavestepButton.BackgroundColor = Color{1};
                idx_str = strfind(app.SelectedfunctionsListBox.Items{idx}, '(Save to file:');
                app.SelectedfunctionsListBox.Items{idx} = app.SelectedfunctionsListBox.Items{idx}(1:idx_str-1);
            end
            app.SelectedfunctionsListBox.Value = app.SelectedfunctionsListBox.ItemsData(idx);
            
            % Local function
            function item = addDatFileName(item)
                % This functions opens a dialog box so the user types in
                % the filename of the .dat file to be saved.
                item.b_save = false;
                if ~isfield(item, 'datFileName') || isempty(item.datFileName)
                    [~,defName,ext] = fileparts(item.info.outFileName);
                else
                    defName = item.datFileName;
                end
                opts.Resize = 'on';
                datName = inputdlg('File name:', 'New .DAT file name', [1 50], {defName}, opts);
                if isempty(datName)
                    disp('Operation Cancelled by User')
                    return
                end
                item.datFileName = datName{1};
                item.b_save = true;
            end
        end

        % Button pushed function: RemoveItemButton
        function RemoveItemButtonPushed(app, event)
            ItemData_selected = app.SelectedfunctionsListBox.Value;
            if~isempty(ItemData_selected)
                idx = arrayfun(@(x) isequaln(x,ItemData_selected), app.SelectedfunctionsListBox.ItemsData);
                app.SelectedfunctionsListBox.Items(idx)=[];
                app.SelectedfunctionsListBox.ItemsData(idx)=[];
            end
        end

        % Button pushed function: SaveconfigButton
        function SaveconfigButtonPushed(app, event)
            % Creates the pipeline in pipeObj before closing the GUI.
            if isempty(app.SelectedfunctionsListBox.Items)
                uialert(app.UIFigure, 'Create a pipeline first!', 'Failed to save config', 'Icon',"warning");
                return
            end
            b_isOK = app.checkPipeline;
            if ~b_isOK
                b_pipeReady = app.createPipe;
            end
            if b_pipeReady
                delete(app)
            else
                figure(app.UIFigure);
            end
        end

        % Button pushed function: SavetofileButton
        function SavetofileButtonPushed(app, event)
            % This callback saves the current pipeline to a .JSON file by
            % calling a method from pipeObj.
            
            app.createPipe;            
            if app.b_isStandAlone
                app.pipeObj.savePipe;
            else
                answer = inputdlg('Name of Pipeline:', 'Save Pipeline Config File', [1 40]);
                if isempty(answer)
                    disp('Operation Cancelled by User')
                    % empty pipeline in pipeObj:
                    app.pipeObj.pipe = struct();
                    return
                end
                answer = answer{:};
                app.pipeObj.savePipe(answer)
                msgbox(['Pipeline saved as ' answer ' in ' fullfile(app.pipeObj.ProtocolObj.SaveDir, 'PipelineConfigFiles')]);
            end
        end

        % Button pushed function: LoadfromfileButton
        function LoadfromfileButtonPushed(app, event)
            % This callback loads a saved pipeline from a .JSON file using
            % the "loadPipe" method from pipeObj.
            
            if app.b_isStandAlone
                [file,Path] = uigetfile('*.json', 'Load Pipeline Config file', 'pipeConfigFile');
            else
                [file,Path] = uigetfile(fullfile(app.pipeObj.ProtocolObj.SaveDir,'*.json'),...
                    'Load Pipeline Config file', 'pipeConfigFile');
            end
            
            if file == 0
                disp('Operation Cancelled by User')
                return
            end
            % Reset function List:
            app.createFuncList;
            % Load Pipeline from file:
            app.pipeObj.loadPipe(fullfile(Path,file))
            % Update ListBox:
            app.fillPipeListBox;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.PipelineConfigurationLabel = uilabel(app.UIFigure);
            app.PipelineConfigurationLabel.Text = 'Pipeline Configuration';
            app.AvailablefunctionsListBoxLabel = uilabel(app.UIFigure);
            app.AvailablefunctionsListBoxLabel.Text = 'Available functions:';
            app.AvailablefunctionsListBox = uilistbox(app.UIFigure);
            app.AvailablefunctionsListBox.Items = {};
            app.AvailablefunctionsListBox.Value = {};
            app.SelectedfunctionsListBoxLabel = uilabel(app.UIFigure);
            app.SelectedfunctionsListBoxLabel.Text = 'Selected functions:';
            app.SelectedfunctionsListBox = uilistbox(app.UIFigure);
            app.SelectedfunctionsListBox.Items = {};
            app.SelectedfunctionsListBox.ValueChangedFcn = createCallbackFcn(app, @SelectedfunctionsListBoxValueChanged, true);
            app.SelectedfunctionsListBox.Value = {};
            app.ButtonUp = uibutton(app.UIFigure, 'push');
            app.ButtonUp.ButtonPushedFcn = createCallbackFcn(app, @ButtonUpPushed, true);
            app.ButtonUp.Icon = 'icon_arrow_up.png';
            app.ButtonUp.Text = '';
            app.ButtonDwn = uibutton(app.UIFigure, 'push');
            app.ButtonDwn.ButtonPushedFcn = createCallbackFcn(app, @ButtonDwnPushed, true);
            app.ButtonDwn.Icon = 'icon_arrow_dwn.png';
            app.ButtonDwn.Text = '';
            app.SaveconfigButton = uibutton(app.UIFigure, 'push');
            app.SaveconfigButton.ButtonPushedFcn = createCallbackFcn(app, @SaveconfigButtonPushed, true);
            app.SaveconfigButton.Text = 'Save config.';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.SetOptionsButton = uibutton(app.UIFigure, 'push');
            app.SetOptionsButton.ButtonPushedFcn = createCallbackFcn(app, @SetOptionsButtonPushed, true);
            app.SetOptionsButton.Text = {'Set'; ' Options'};
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = {'* items with (!) have optional parameters.'; 'Click in "Set Options" to change them.'};
            app.AddButton = uibutton(app.UIFigure, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = '>>Add>>';
            app.RemoveItemButton = uibutton(app.UIFigure, 'push');
            app.RemoveItemButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveItemButtonPushed, true);
            app.RemoveItemButton.Text = {'Remove'; ' Item'; ''};
            app.SavestepButton = uibutton(app.UIFigure, 'push');
            app.SavestepButton.ButtonPushedFcn = createCallbackFcn(app, @SavestepButtonPushed, true);
            app.SavestepButton.Text = {'Save '; 'step'; ''};
            app.SavetofileButton = uibutton(app.UIFigure, 'push');
            app.SavetofileButton.ButtonPushedFcn = createCallbackFcn(app, @SavetofileButtonPushed, true);
            app.SavetofileButton.Text = 'Save to file...';
            app.LoadfromfileButton = uibutton(app.UIFigure, 'push');
            app.LoadfromfileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadfromfileButtonPushed, true);
            app.LoadfromfileButton.Text = 'Load from file...';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PipelineConfig_dlgBox(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end