classdef PlotLongData < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        SaveExportMenu                 matlab.ui.container.Menu
        SavefiguresMenu                matlab.ui.container.Menu
        ExportfiguresMenu              matlab.ui.container.Menu
        SelectDataMenu                 matlab.ui.container.Menu
        DatainmainfigureDropDownLabel  matlab.ui.control.Label
        DatainmainfigureDropDown       matlab.ui.control.DropDown
        AxissettingsPanel              matlab.ui.container.Panel
        YlabelEditFieldLabel           matlab.ui.control.Label
        YlabelEditField                matlab.ui.control.EditField
        YscaleButtonGroup              matlab.ui.container.ButtonGroup
        AutoButton                     matlab.ui.control.RadioButton
        UniformButton                  matlab.ui.control.RadioButton
        LinearCheckBox                 matlab.ui.control.CheckBox
        LogCheckBox                    matlab.ui.control.CheckBox
        FixedButton                    matlab.ui.control.RadioButton
        minEditFieldLabel              matlab.ui.control.Label
        minEditField                   matlab.ui.control.NumericEditField
        maxEditFieldLabel              matlab.ui.control.Label
        maxEditField                   matlab.ui.control.NumericEditField
        ErrorbarstyleButtonGroup       matlab.ui.container.ButtonGroup
        StandarddeviationSDButton      matlab.ui.control.RadioButton
        StandardErrorofthemeanSEMButton  matlab.ui.control.RadioButton
        ConfidenceintervalCIButton     matlab.ui.control.RadioButton
    end

    
    properties (Access = private)
        statsObj % StatManager object containing ROI data.
        mainFig % Handle to the main figure.
        summaryFigs % Handle to Summary figures.
        mainAx % Handle to axis in main figure;
        scatData % Structure containing the metaData of each item from the scatter plots in the summary figures.
        mainAxMetaData % MetaData for each scatter point in the main figure;
        dcm_obj % DataCursor mode object for datatips in Main Figure.
        cmp % current mouse position. This property is updated by the WindowButtonMotionFcn of the main fig.
        errMeasure % Measurement of error to be represented in the error bars (i.e. 'SD', 'SEM', '95%CI').
        hlink  % linkprop object handle.        
    end
    
    properties (Access = public)
        data % This is the data that from StatsObj that was repackaged for plotting by the "Data_selector" app.
    end
    
    methods (Access = private)
        
        function createSummaryFigs(app)
            % This method creates figures with line + scatter plots. A maximum of 8 plots
            % is allowed per figure.
            
            % Create first figure:
            app.summaryFigs = figure('Name', ['Summary figure #' num2str(1)], 'NumberTitle', 'off',...
                'Position',[0 0 600 700],'Visible','off', 'CloseRequestFcn',@app.DoNothing);
            movegui(app.summaryFigs(1), 'southeast');
            cnt = 1; % Axes counter.
            % Create a plot for each observation:
            % Loop over the number of groups:
            gList = app.data(1).gName;
            colList = app.data(1).gColor;
            app.scatData = struct('handles',[],'metaData',{});
            plotTitleList = {app.data.plotTitle};
            for ii = 1:numel(plotTitleList)
                % Create axis:
                s(ii) = subplot(4,2,cnt,"replace",'Parent',app.summaryFigs(end), 'Tag', plotTitleList{ii});
                hold(s(ii),"on")
                
                for jj = 1:numel(gList)
                    idx = ( app.data(ii).groupIndx == jj );
                    dat = app.data(ii).data(idx,:);
                    % Create errorbar plot (default):
                    % Calculate average and STD of data by acqIndx and create errorbar plot:
                    avg = mean(dat,1,'omitnan');
                    stDev = std(dat,0,1,'omitnan');                   
                    e = errorbar(s(ii),1:length(avg),avg,stDev, 'xk','Tag', ['errG' num2str(jj)], 'Color', colList(jj,:));
                    e.CapSize = 10;
                    e.LineWidth = 1;                    
                    % Create scatter plot:
                    xVec = repmat(1:length(avg), size(dat,1),1);
                    scat = scatter(s(ii),xVec(:),dat(:),'filled');
                    set(scat, 'MarkerEdgeColor', 'none', 'MarkerFaceColor', colList(jj,:), 'MarkerFaceAlpha',.5, 'Tag', ['scatG' num2str(jj)]);
                    app.scatData(ii).handles(jj) = scat;
                    % Add the metaData info for Data Tips:                    
                    metaData = app.data(ii).dataTip(idx,:);
                    app.scatData(ii).metaData{jj} = metaData(:);
                    app.scatData(ii).gColor{jj} = repmat(app.data(ii).gColor(jj,:),numel(metaData),1);
                end
                                
                title(s(ii),plotTitleList{ii}, 'Interpreter',"none");
                hold(s(ii),"off")
                cnt = cnt+1;
                if ( cnt > 8 )
                    cnt = 1;
                    if ( ii < numel(plotTitleList) )
                        app.summaryFigs = vertcat(app.summaryFigs,...
                            figure('Name', ['Summary figure #' num2str(length(app.summaryFigs)+1)], 'NumberTitle', 'off',...
                            'Position',[0 0 600 700],'Visible','off', 'CloseRequestFcn',@app.DoNothing));
                        movegui(app.summaryFigs(end), 'southeast');
                    end
                end
            end
            % Link axes properties and set some of them:
            app.hlink = linkprop(s,{'XLim','YLimMode', 'YScale'});            
            arrayfun(@(x) set(x, 'XLim', [0 size(app.data(1).data,2)+1], 'XTick',1:size(app.data(1).data,2), 'XTickLabel', app.data(1).xLabel, ...
                'TickLabelInterpreter', 'none', 'Color',[.95 .95 .95], 'XTickLabelRotation', -23), s)                      
            % Show figures:
            arrayfun(@(x) set(x, 'Visible', 'on'), app.summaryFigs);
        end
                
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% CUSTOM CALLBACKS %%%%%%%%%%%%%%%%%%%%%%
        function DoNothing(~,~,~)
            % EMPTY!%
        end
        
        function txt = showDataTip(app,~,evnt)
            % Callback to show object's information in datatip.
            
            % If the user clicked on a scatter plot dot:
            if isa(evnt.Target, 'matlab.graphics.chart.primitive.Scatter')
                indx = str2double(erase(evnt.Target.Tag, 'scatG'));                 
                info = app.mainAxMetaData.metaData{indx}{evnt.Position(2) == evnt.Target.YData};                                
                col = app.mainAxMetaData.gColor{indx}(evnt.Position(2) == evnt.Target.YData,:);
            elseif isa(evnt.Target, 'matlab.graphics.chart.primitive.ErrorBar')
                info = sprintf('Mean: %0.3f\nError: \\pm %0.3f',evnt.Target.YData(evnt.Position(1)), ...
                    evnt.Target.YPositiveDelta(evnt.Position(1)));
                col = evnt.Target.Color;
            else
                info = sprintf('Single Line\nX: %d\nY: %0.3f', evnt.Position(1), evnt.Position(2));
                col = evnt.Target.Color;
            end
            info = strrep(info, '_','\_'); % To keep Tex interpreter and avoid subscripting text.
            txt = [sprintf(' \\color[rgb]{%0.4f %0.4f %0.4f}\\bfValue: %0.2f\n',...
                    col(1), col(2), col(3), evnt.Position(2)), info];               
        end

        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% %%%%%%
       
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, statsObj)
            
            % Check if input data from statsObj are single points in time (i.e. one data point per acquisition per subject):  
            app.UIFigure.Visible = 'off';
            if ~strcmpi(statsObj.inputFeatures.dataType, 'scalar')
                w = errordlg('This app does not support non-scalar data points.', 'Invalid input', "modal");
                waitfor(w);
                app.UIFigure.Visible = 'on';
                app.UIFigureCloseRequest;
                return
            end                       
            %             
            movegui(app.UIFigure, 'northwest');
            
            app.statsObj = statsObj;
            % Get data from "Data_selector" app with default dimension order (acquisition on the x axis, groups on the y and ROIs per plot):
            ds = Data_selector(statsObj, app,false);
            waitfor(ds);
                        
            % Populate dropdown list:
            app.DatainmainfigureDropDown.Items = {app.data.plotTitle};
            % Create main figure:
            app.mainFig = figure('Name', ['Main - ' app.DatainmainfigureDropDown.Value], 'NumberTitle','off',...
                'Position', [0 0 880 420],'Visible', 'off', 'CloseRequestFcn', @app.DoNothing);
            app.mainAx = axes('Parent', app.mainFig, 'Position',[.1 .2 .8 .7], 'Units',"normalized");
            % Create summary figures:
            app.createSummaryFigs;
            % Show Figures and app:
            app.ShowPlotInMainFig;
            movegui(app.mainFig, 'center');
            % Set some axis properties of mainAx:
            app.mainAx.Title.Interpreter = 'none';            
            app.mainAx.YLabel.String = 'Value';
            %
            app.minEditField.Value = double(app.mainAx.YLim(1));
            app.maxEditField.Value = double(app.mainAx.YLim(2));
            % Add mainAx to hlink object:
            app.hlink.addtarget(app.mainAx)
            % Set Data cursor info:
            app.dcm_obj = datacursormode(app.mainFig);
            set(app.dcm_obj, 'DisplayStyle', 'datatip', 'Enable', 'off','UpdateFcn', @app.showDataTip, 'Interpreter', 'tex');
            % Check for Statistics toolbox:
            a = ver;
            if any(strcmp('Statistics and Machine Learning Toolbox', {a.Name}))
                app.ConfidenceintervalCIButton.Enable = 'on';
            end
            % Make it visible:
            app.mainFig.Visible = 'on';
            app.UIFigure.Visible = 'on';
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app.mainFig)
            delete(app.summaryFigs)
            delete(app)
        end

        % Value changed function: DatainmainfigureDropDown
        function ShowPlotInMainFig(app, event)
            % This callback makes a copy of selected ROI plot from the
            % summaryFigs into the main fig axis
            
            idx_plot = strcmp(app.DatainmainfigureDropDown.Value, app.DatainmainfigureDropDown.Items);
            cla(app.mainAx);
            % Copy source axis content to main plot:
            copyobj(app.scatData(idx_plot).handles(1).Parent.Children,app.mainAx);
            % Copy title and labels:    
            app.mainAx.Title.String = app.scatData(idx_plot).handles(1).Parent.Title.String;
            app.mainAxMetaData = app.scatData(idx_plot);
            app.mainAx.YLim = app.scatData(idx_plot).handles(1).Parent.YLim;
            app.mainAx.XTick  = app.scatData(idx_plot).handles(1).Parent.XTick;
            app.mainAx.XTickLabel = app.scatData(idx_plot).handles(1).Parent.XTickLabel;
            app.mainAx.XTickLabelRotation = app.scatData(idx_plot).handles(1).Parent.XTickLabelRotation;
            app.mainAx.XLabel.String = app.data(1).xTitle;
            % Update legend:
            errBarName = regexp(app.ErrorbarstyleButtonGroup.SelectedObject.Text, '(?<=\()\w*\)', 'match', 'once');            
            gNames = cellfun(@(x) [x ' (\pm' errBarName], app.data(1).gName, 'UniformOutput', false);
            legend(app.mainAx, app.scatData(idx_plot).handles,gNames,'AutoUpdate',"off", 'Location',"bestoutside")
        end

        % Selection changed function: ErrorbarstyleButtonGroup
        function ErrorbarstyleButtonGroupSelectionChanged(app, event)
            % This callback updates the error bars of all plots as well as
            % the legend in the main figure for the type of error measure
            % selected in the GUI.
            
            indx = find(arrayfun(@(x) isequaln(app.ErrorbarstyleButtonGroup.SelectedObject, x), app.ErrorbarstyleButtonGroup.Buttons));
            for i = 1:length(app.scatData)
                for j = 1:length(app.scatData(i).handles)
                    h = findobj(app.scatData(i).handles(1).Parent, 'Tag',strrep(app.scatData(i).handles(j).Tag, 'scat', 'err'));
                    stDev = arrayfun(@(x) std(app.scatData(i).handles(j).YData(app.scatData(i).handles(j).XData == x),0,"all", 'omitnan'),...
                        unique(app.scatData(i).handles(j).XData));                    
                    switch indx
                        case 1
                            % SD
                            set(h, 'YPositiveDelta', stDev, 'YNegativeDelta', stDev);
                            
                        case 2
                            %SEM
                            set(h, 'YPositiveDelta', stDev./sqrt(sum(app.scatData(i).handles(j).XData == 1)),...
                                'YNegativeDelta', stDev./sqrt(sum(app.scatData(i).handles(j).XData == 1)));
                        case 3
                            %95%CI
                            set(h, 'YPositiveDelta',  (tinv(1-0.025,sum(app.scatData(i).handles(j).XData == 1)-1)).*(stDev./sqrt(sum(app.scatData(i).handles(j).XData == 1))),...
                                'YNegativeDelta',  (tinv(1-0.025,sum(app.scatData(i).handles(j).XData == 1)-1)).*(stDev./sqrt(sum(app.scatData(i).handles(j).XData == 1))));
                    end
                end
               
            end
            
            % Update Main figure as well:
            app.ShowPlotInMainFig;
        
        end

        % Value changed function: YlabelEditField
        function YlabelEditFieldValueChanged(app, event)
            
            app.mainAx.YLabel.String = app.YlabelEditField.Value;           
        end

        % Selection changed function: YscaleButtonGroup
        function YscaleButtonGroupSelectionChanged(app, event)
            selectedButton = app.YscaleButtonGroup.SelectedObject;
            indx = find(arrayfun(@(x) isequaln(x,selectedButton), app.YscaleButtonGroup.Buttons));
            app.minEditField.Enable = 'off';
            app.maxEditField.Enable = 'off';
            switch indx
                case 1
                    % Auto
                    app.hlink.removeprop('YLim');
                    set(app.mainAx, 'YLimMode','auto')
                case 2
                    % Uniform
                    set(app.mainAx, 'YLimMode','auto')
                    y_lims = arrayfun(@(x) x.handles(1).Parent.YLim, app.scatData, 'UniformOutput',false); y_lims = y_lims';
                    y_lims = vertcat(y_lims{:});
                    y_lims = [min(y_lims(:,1)), max(y_lims(:,2))];
                    app.hlink.addprop('YLim');
                    set(app.mainAx, 'YLim',y_lims,'YLimMode','manual');
                case 3
                    % Fixed
                    app.minEditField.Enable = 'on';
                    app.maxEditField.Enable = 'on';
            end
            % Set min and max YLims values from main ax:
            app.minEditField.Value = double(app.mainAx.YLim(1));
            app.maxEditField.Value = double(app.mainAx.YLim(2));
        end

        % Value changed function: maxEditField, minEditField
        function min_max_EditFieldValueChanged(app, event)
            app.minEditField.Value;
            app.hlink.addprop('YLim');
            set(app.mainAx, 'YLim', [app.minEditField.Value, app.maxEditField.Value], 'YLimMode','manual');
        end

        % Value changed function: LinearCheckBox
        function LinearCheckBoxValueChanged(app, event)
            if app.LinearCheckBox.Value
                app.LogCheckBox.Value = false;
                set(app.mainAx, 'YScale', 'linear')
            else 
                app.LinearCheckBox.Value = true;
            end
        end

        % Value changed function: LogCheckBox
        function LogCheckBoxValueChanged(app, event)
            if app.LogCheckBox.Value
                app.LinearCheckBox.Value = false;
                set(app.mainAx, 'YScale', 'log')
            else 
                app.LogCheckBox.Value = true;
            end
        end

        % Menu selected function: ExportfiguresMenu, SavefiguresMenu
        function Save_Export_Figs(app, event)
            % This callback either saves as .fig or exports individual plots as
            % bitmap/vector images.
            
            % Create format if the User chose to save as .fig:
            if startsWith(event.Source.Text, 'save', "IgnoreCase",true)               
                [file,path] = uiputfile('*.fig', 'Save plots as...', 'LongDatPlots');
            else
                fig_filter = {'*.png', 'PNG 24-bit (*.png)'; '*.jpg', 'JPEG 24-bit  (*.jpg)';...
                '*.pdf', 'Portable Document Format  (*.pdf)';...
                '*.eps', 'Encapsulated PostScript  (*.eps)'};
                [file, path, indx] = uiputfile(fig_filter, 'Export Image(s)', 'LongDatPlots.png');
                print_dict = {'-dpng', '-djpeg', '-dpdf', '-depsc'};
            end
            if file == 0
              disp('Operation cancelled by User')
              return
            end
                        
            [~,prefix,fmt] = fileparts(file);            
            f = figure('Position',[0 0 880 420], 'Visible', 'off');
            movegui(f, 'center');f.Visible = 'on';
            h = waitbar(0,'Saving figures...'); movegui(h,'northeast');
            legH = app.mainFig.Children(arrayfun(@(x) isa(x, 'matlab.graphics.illustration.Legend'), app.mainFig.Children));
            gNames = regexp(legH.String,'\w*(?=\s*\(\\pm)','match'); gNames = [gNames{:}];
            for i = 1:length(app.scatData)                
                ax = axes(f, 'Units',"normalized" , 'Position',[.1 .12 .8 .8]);
                copyobj(app.scatData(i).handles(1).Parent.Children, ax)
                set(ax, 'Color', app.scatData(i).handles(1).Parent.Color, 'XLim', app.scatData(i).handles(1).Parent.XLim, ...
                    'YLim', app.scatData(i).handles(1).Parent.YLim, 'YScale', app.scatData(i).handles(1).Parent.YScale);
                ax.XLabel.String = app.mainAx.XLabel.String;
                ax.YLabel.String = app.mainAx.YLabel.String;                                               
                legend(ax, app.scatData(i).handles,gNames,'AutoUpdate',"off", 'Location',"best");
                title(ax, app.scatData(i).handles(1).Parent.Title.String, 'Interpreter', 'none')
                if startsWith(event.Source.Text, 'save', "IgnoreCase",true)                    
                    savefig(f, fullfile(path,[prefix '_' ax.Title.String fmt]))
                else
                    if strcmp(fmt, '.pdf')
                        print([prefix '_' ax.Title.String fmt] , f, print_dict{indx}, '-painters','-r300', '-bestfit');
                    else
                        print([prefix '_' ax.Title.String fmt] , f, print_dict{indx}, '-painters','-r300');
                    end
                end
                clf(f);                 
                waitbar(i/length(app.scatData), h);
            end
            close(h)
            close(f)
            
            
        end

        % Menu selected function: SelectDataMenu
        function SelectDataMenuSelected(app, event)
            % This callback erases the current summary figures and launches
            % the "Data_selector" to rebuild all figures. 
             
            % Clear summary figures and main axis:
            arrayfun(@delete, app.summaryFigs);app.summaryFigs = [];
            cla(app.mainAx);
            % Hide main figure and GUI:
            app.UIFigure.Visible = 'off';
            app.mainFig.Visible = 'off';
            % Launch Data_selector;
            ds = Data_selector(app.statsObj, app, true);
            waitfor(ds);            
            % Rebuild summary figures and update GUI:            
            app.DatainmainfigureDropDown.Items = {app.data.plotTitle};                                   
            app.createSummaryFigs;            
            app.ShowPlotInMainFig;
            movegui(app.mainFig, 'center');
            
            app.mainFig.Visible = 'on';
            app.UIFigure.Visible = 'on';                       
            % Relink main axis:
            app.hlink.addtarget(app.mainAx)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Longitudinal data Plotter';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.SaveExportMenu = uimenu(app.UIFigure);
            app.SaveExportMenu.Text = 'Save/Export';
            app.SavefiguresMenu = uimenu(app.SaveExportMenu);
            app.SavefiguresMenu.MenuSelectedFcn = createCallbackFcn(app, @Save_Export_Figs, true);
            app.SavefiguresMenu.Text = 'Save figures';
            app.ExportfiguresMenu = uimenu(app.SaveExportMenu);
            app.ExportfiguresMenu.MenuSelectedFcn = createCallbackFcn(app, @Save_Export_Figs, true);
            app.ExportfiguresMenu.Text = 'Export figures';
            app.SelectDataMenu = uimenu(app.UIFigure);
            app.SelectDataMenu.MenuSelectedFcn = createCallbackFcn(app, @SelectDataMenuSelected, true);
            app.SelectDataMenu.Text = 'Select Data';
            app.DatainmainfigureDropDownLabel = uilabel(app.UIFigure);
            app.DatainmainfigureDropDownLabel.Text = 'Data in main figure';
            app.DatainmainfigureDropDown = uidropdown(app.UIFigure);
            app.DatainmainfigureDropDown.ValueChangedFcn = createCallbackFcn(app, @ShowPlotInMainFig, true);
            app.AxissettingsPanel = uipanel(app.UIFigure);
            app.AxissettingsPanel.Title = 'Axis settings';
            app.YlabelEditFieldLabel = uilabel(app.AxissettingsPanel);
            app.YlabelEditFieldLabel.Text = 'Y label';
            app.YlabelEditField = uieditfield(app.AxissettingsPanel, 'text');
            app.YlabelEditField.ValueChangedFcn = createCallbackFcn(app, @YlabelEditFieldValueChanged, true);
            app.YlabelEditField.Value = 'Value';
            app.YscaleButtonGroup = uibuttongroup(app.AxissettingsPanel);
            app.YscaleButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @YscaleButtonGroupSelectionChanged, true);
            app.YscaleButtonGroup.Title = 'Y scale';
            app.AutoButton = uiradiobutton(app.YscaleButtonGroup);
            app.AutoButton.Tooltip = {'Sets the best Y limits for each plot independently.'};
            app.AutoButton.Text = 'Auto';
            app.AutoButton.Value = true;
            app.UniformButton = uiradiobutton(app.YscaleButtonGroup);
            app.UniformButton.Tooltip = {'Sets all Y limits for all plots using the largest range found across plots.'};
            app.UniformButton.Text = 'Uniform';
            app.LinearCheckBox = uicheckbox(app.YscaleButtonGroup);
            app.LinearCheckBox.ValueChangedFcn = createCallbackFcn(app, @LinearCheckBoxValueChanged, true);
            app.LinearCheckBox.Text = 'Linear';
            app.LinearCheckBox.Value = true;
            app.LogCheckBox = uicheckbox(app.YscaleButtonGroup);
            app.LogCheckBox.ValueChangedFcn = createCallbackFcn(app, @LogCheckBoxValueChanged, true);
            app.LogCheckBox.Text = 'Log';
            app.FixedButton = uiradiobutton(app.YscaleButtonGroup);
            app.FixedButton.Tooltip = {'Set Y limits manually.'};
            app.FixedButton.Text = 'Fixed';
            app.minEditFieldLabel = uilabel(app.YscaleButtonGroup);
            app.minEditFieldLabel.Text = 'min';
            app.minEditField = uieditfield(app.YscaleButtonGroup, 'numeric');
            app.minEditField.ValueDisplayFormat = '%11.3f';
            app.minEditField.ValueChangedFcn = createCallbackFcn(app, @min_max_EditFieldValueChanged, true);
            app.minEditField.Enable = 'off';
            app.maxEditFieldLabel = uilabel(app.YscaleButtonGroup);
            app.maxEditFieldLabel.Text = 'max';
            app.maxEditField = uieditfield(app.YscaleButtonGroup, 'numeric');
            app.maxEditField.ValueDisplayFormat = '%11.3f';
            app.maxEditField.ValueChangedFcn = createCallbackFcn(app, @min_max_EditFieldValueChanged, true);
            app.maxEditField.Enable = 'off';
            app.ErrorbarstyleButtonGroup = uibuttongroup(app.AxissettingsPanel);
            app.ErrorbarstyleButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ErrorbarstyleButtonGroupSelectionChanged, true);
            app.ErrorbarstyleButtonGroup.Title = 'Error bar style';
            app.StandarddeviationSDButton = uiradiobutton(app.ErrorbarstyleButtonGroup);
            app.StandarddeviationSDButton.Text = 'Standard deviation (SD)';
            app.StandarddeviationSDButton.Value = true;
            app.StandardErrorofthemeanSEMButton = uiradiobutton(app.ErrorbarstyleButtonGroup);
            app.StandardErrorofthemeanSEMButton.Text = 'Standard Error of the mean (SEM)';
            app.ConfidenceintervalCIButton = uiradiobutton(app.ErrorbarstyleButtonGroup);
            app.ConfidenceintervalCIButton.Enable = 'off';
            app.ConfidenceintervalCIButton.Tooltip = {'available with Statistics and Machine Learning Toolbox.'};
            app.ConfidenceintervalCIButton.Text = '95% Confidence interval (CI)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PlotLongData(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end